classdef HMI_proyecto_intermedio_robotica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UNIVERSIDADNACIONALDECOLOMBIALabel  matlab.ui.control.Label
        SEDEBOGOTALabel      matlab.ui.control.Label
        Robotica2020IILabel  matlab.ui.control.Label
        Image_logo           matlab.ui.control.Image
        lbl_estado           matlab.ui.control.Label
        img_estado           matlab.ui.control.Image
        btn_start            matlab.ui.control.Button
        btn_stop             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_start
        function btn_startButtonPushed(app, event)
            try                                                                    % Intenta inicializar el nodo de matlab. Si no falla:
                rosinit;                                                                % Inicializa el nodo de matlab en ros.
                waitbar(0.25,'Inicio d  el nodo de matlab, por favor espere....');      % Informa al usuario del inicio del nodo de matlab.
                waitbar(0.5,'Iniciando el nodo de matlab, por favor espere....');       % Informa al usuario del inicio del nodo de matlab.
                waitbar(0.75,'ya casi terminamos, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                waitbar(1,'Nodo inicializado....');                                     % Informa al usuario del inicio del nodo de matlab.                
                close all;                                                              % Cierra todas las ventanas de Informacion.
                app.img_estado.ImageSource = 'exito.png';                               % Cambia la imagen de estado a exito.png.
                app.lbl_estado.Text = 'Estado: Nodo creado!';                           % Cambia el lbl de estado a Nodo creado.
                
            catch                                                                  % Si falla en la cracion del nodo:
                app.img_estado.ImageSource = 'warning.png';                           % Cambia la imagen de estado a warning.png.
                app.lbl_estado.Text = 'Estado: El nodo no fue creado!';               % Cambia el lbl de estado a El nodo no fue creado.
            end
        end

        % Button pushed function: btn_stop
        function btn_stopButtonPushed(app, event)
            rosshutdown;                                    % Apaga el nodo de matlab.
            app.img_estado.ImageSource = 'en_espera.png';   % Cambia la imagen de estado a en espera.
            app.lbl_estado.Text = 'En espera!';             % Cambia el lbl de estado a En espera.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UNIVERSIDADNACIONALDECOLOMBIALabel = uilabel(app.UIFigure);
            app.UNIVERSIDADNACIONALDECOLOMBIALabel.Text = 'UNIVERSIDAD NACIONAL DE COLOMBIA';
            app.SEDEBOGOTALabel = uilabel(app.UIFigure);
            app.SEDEBOGOTALabel.Text = 'SEDE BOGOTA';
            app.Robotica2020IILabel = uilabel(app.UIFigure);
            app.Robotica2020IILabel.Text = 'Robotica 2020-II';
            app.Image_logo = uiimage(app.UIFigure);
            app.Image_logo.ImageSource = 'logou.png';
            app.lbl_estado = uilabel(app.UIFigure);
            app.lbl_estado.Text = 'Estado: En espera';
            app.img_estado = uiimage(app.UIFigure);
            app.img_estado.ImageSource = 'en_espera.png';
            app.btn_start = uibutton(app.UIFigure, 'push');
            app.btn_start.ButtonPushedFcn = createCallbackFcn(app, @btn_startButtonPushed, true);
            app.btn_start.Icon = 'start.png';
            app.btn_start.Tooltip = {'Pulse aqui para iniciar el proceso'};
            app.btn_start.Text = '';
            app.btn_stop = uibutton(app.UIFigure, 'push');
            app.btn_stop.ButtonPushedFcn = createCallbackFcn(app, @btn_stopButtonPushed, true);
            app.btn_stop.Icon = 'stop.jpg';
            app.btn_stop.Tooltip = {'Pulse aqui para detener el proceso.'};
            app.btn_stop.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HMI_proyecto_intermedio_robotica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end